classdef MatProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.Button
        Label          matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        Label_2        matlab.ui.control.Label
        DropDown_2     matlab.ui.control.DropDown
        Label_3        matlab.ui.control.Label
        DropDown_3     matlab.ui.control.DropDown
        Label_4        matlab.ui.control.Label
        DropDown_4     matlab.ui.control.DropDown
        UIAxes         matlab.ui.control.UIAxes
        Label_5        matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        Slider_2Label  matlab.ui.control.Label
        Slider_2       matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [FileID, PathID, FilterIndex] = uigetfile({'*.xlsx'; '*.xlsb'; '*.xlsm'; '*.xls'; '*.xltm'; '*.xltx'; '*.ods'}, 'Укажите путь к таблице excel');
            global VVP;
            global X;
            global T1;
            global T2;
            global T3;
            global T4;
            VVP = readtable([PathID, FileID]);
            X = VVP(:, 2:60).Variables;
            M = isnan(X);
            X(M) = 0;
            VVP(:, 2:60).Variables = X;
            Z = VVP(:, 2:width(VVP)).Properties.VariableNames;
            X = [];
            for i=1:(width(VVP)-1)
            M = cell2mat(Z(i));
            X = [X, str2num(M(2:5))];
            end
            global YEARS;
            YEARS = X;
            VVP.Properties.RowNames = VVP(:, 1).Variables;
            VVP(:, 1) = [];
            app.DropDown.Items = VVP.Properties.RowNames;
            app.DropDown_2.Items = VVP.Properties.RowNames;
            app.DropDown_3.Items = VVP.Properties.RowNames;
            app.DropDown_4.Items = VVP.Properties.RowNames;
            hold(app.UIAxes);
            app.Slider.Limits = [min(YEARS), max(YEARS)];
            app.Slider_2.Limits = [min(YEARS), max(YEARS)];
            app.Slider.Value = min(YEARS);
            app.Slider_2.Value = max(YEARS);
            VVP.Properties.VariableNames = strsplit(num2str(YEARS));
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global T1;
            delete(T1);
            global VVP;
            global X;
            value = app.DropDown.Value;
            T1 = plot(app.UIAxes, X, VVP(value, strsplit(num2str(X))).Variables, '-r');
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            global T2;
            delete(T2);
            value = app.DropDown_2.Value;
            global VVP;
            global X;
            T2 = plot(app.UIAxes, X, VVP(value, strsplit(num2str(X))).Variables, '--b');
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            global T3;
            delete(T3);
            value = app.DropDown_3.Value;
            global VVP;
            global X;
            T3 = plot(app.UIAxes, X, VVP(value, strsplit(num2str(X))).Variables, ':k');
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            global T4;
            delete(T4);
            value = app.DropDown_4.Value;
            global VVP;
            global X;
            T4 = plot(app.UIAxes, X, VVP(value, strsplit(num2str(X))).Variables, '-.g');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global YEARS;
            global X;
            value = app.Slider.Value;
            if (value >= app.Slider_2.Value)
                errordlg('Неправильно выбран год', 'Поменяйте год');
                app.Slider.Value = min(YEARS); 
            else
                cla(app.UIAxes);
                X = X(X > value);
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            global YEARS;
            global X;
            value = app.Slider_2.Value;
            if (value <= app.Slider.Value)
                errordlg('Неправильно выбран год', 'Поменяйте год');
                app.Slider_2.Value = max(YEARS); 
            else
                cla(app.UIAxes);
                X = X(X < value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузить таблицу';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Страна 1';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Страна 2';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Страна 3';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Страна 4';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ВВП')
            xlabel(app.UIAxes, 'Years')
            ylabel(app.UIAxes, 'Gross Domestic Product')
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'Нижняя'; 'граница'};
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = {'Верхняя'; 'граница'};
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end